classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VstupyMenu                     matlab.ui.container.Menu
        CasyMenu                       matlab.ui.container.Menu
        XLSX_suborMenu                 matlab.ui.container.Menu
        CSV_suborMenu                  matlab.ui.container.Menu
        MAT_suborMenu                  matlab.ui.container.Menu
        PaketyzCSVMenu                 matlab.ui.container.Menu
        XLSX_paketyMenu                matlab.ui.container.Menu
        CSV_paketyMenu                 matlab.ui.container.Menu
        MAT_paketyMenu                 matlab.ui.container.Menu
        PCAPsuborMenu                  matlab.ui.container.Menu
        FunkcieMenu                    matlab.ui.container.Menu
        HurstMenu                      matlab.ui.container.Menu
        Hurst3DMenu                    matlab.ui.container.Menu
        AR1Menu                        matlab.ui.container.Menu
        KoeficientvariabilnostiMenu    matlab.ui.container.Menu
        DetaielnejsiVystup             matlab.ui.container.Menu
        UpravaVstupu                   matlab.ui.container.Menu
        Panel_Hurst3D                  matlab.ui.container.Panel
        EditField_Hurst3D_5            matlab.ui.control.NumericEditField
        Hodnota1Label_5                matlab.ui.control.Label
        EditField_Hurst3D_4            matlab.ui.control.NumericEditField
        Hodnota1Label_4                matlab.ui.control.Label
        EditField_Hurst3D_3            matlab.ui.control.NumericEditField
        Hodnota1Label_3                matlab.ui.control.Label
        EditField_Hurst3D_2            matlab.ui.control.NumericEditField
        Hodnota1Label_2                matlab.ui.control.Label
        EditField_Hurst3D_1            matlab.ui.control.NumericEditField
        Hodnota1Label                  matlab.ui.control.Label
        LabelHurst3DVstup              matlab.ui.control.Label
        ButtonHurstNacitatSubor        matlab.ui.control.Button
        LabelHurstInfo_2               matlab.ui.control.Label
        ButtonUkonciHurst3D            matlab.ui.control.Button
        ButtonVypocetHurst3D           matlab.ui.control.Button
        ButtonResetovat                matlab.ui.control.Button
        LabelHurstInfo                 matlab.ui.control.Label
        UIAxesHurst3D                  matlab.ui.control.UIAxes
        UlozTimestampButton            matlab.ui.control.Button
        UlozPacketLengthButton         matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        NatansborEditFieldLabel        matlab.ui.control.Label
        UlozPrirastkyCasuButton        matlab.ui.control.Button
        UlozkBPrirastkyButton          matlab.ui.control.Button
        Panel_UpravaVstupu             matlab.ui.container.Panel
        NacitatVstupButton             matlab.ui.control.Button
        UkoniButton                    matlab.ui.control.Button
        Panel_StrihajPo                matlab.ui.control.NumericEditField
        Panel_dvojbodka                matlab.ui.control.Label
        Panel_StrihajOd                matlab.ui.control.NumericEditField
        VystrihniButton                matlab.ui.control.Button
        Panel_UIAxes                   matlab.ui.control.UIAxes
        PrepocitajButton               matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        EditField_NacitanySubor        matlab.ui.control.EditField
        EditField_LengthOfData         matlab.ui.control.EditField
        VekosnatanchdtLabel            matlab.ui.control.Label
        NovvekospresamplewindowLabel   matlab.ui.control.Label
        EditField_MeanOfTimes          matlab.ui.control.EditField
        EditField_MeanPackets          matlab.ui.control.EditField
        EditField_StandardDeviation    matlab.ui.control.EditField
        EditField_StandardDeviation_2  matlab.ui.control.EditField
        EditField_SampleWindow         matlab.ui.control.EditField
        EditField_ChangeSampleWindow   matlab.ui.control.NumericEditField
        Button_ChangeScale_a           matlab.ui.control.Button
        Scale_a_2                      matlab.ui.control.NumericEditField
        tandardnodchlkaLabel_2         matlab.ui.control.Label
        Label_Te_3                     matlab.ui.control.Label
        Scale_a_1                      matlab.ui.control.NumericEditField
        tandardnodchlkaLabel_3         matlab.ui.control.Label
        PriemerLabel_2                 matlab.ui.control.Label
        tandardnodchlkaLabel           matlab.ui.control.Label
        PriemerLabel                   matlab.ui.control.Label
        Button_ChangeScale_kB          matlab.ui.control.Button
        Scale_kB2                      matlab.ui.control.NumericEditField
        Label_Te_2                     matlab.ui.control.Label
        Scale_kB1                      matlab.ui.control.NumericEditField
        Label_Kumulativ                matlab.ui.control.Label
        Button_ChangeScale_Te          matlab.ui.control.Button
        Scale_Te2                      matlab.ui.control.NumericEditField
        Label_Te                       matlab.ui.control.Label
        Scale_Te1                      matlab.ui.control.NumericEditField
        Scale_Kumulativ1               matlab.ui.control.NumericEditField
        Button_ChangeScale_Kumulativ   matlab.ui.control.Button
        Scale_Kumulativ2               matlab.ui.control.NumericEditField
        UIAxes_Prirastky               matlab.ui.control.UIAxes
        UIAxes_KB                      matlab.ui.control.UIAxes
        UIAxes_Te                      matlab.ui.control.UIAxes
        UIAxes_Kumulativ               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename,path;
        Timestamp;              % ulozene data s casmy
        PacketLength;           % ulozene data s velkostami paketov
        file;                   % subor
        N;                      % velkost PCAP suboru
        value;                  % checkbox value
        te;                     % medzery
        T2;                     % vektor nacitanych dat (casy, pakety)
        data1;                  % pomocny parameter pri nacitavani dat
        x,y;
        dh,hh;                  % dolna a horna hranica pre sigmu
        sigma;                  % sigma
        scaleFrom;              % os od
        scaleTo;                % os do
        standardDeviation;      % standardna odchylka
        priemer;                % priemer
        visibilityOfCheckBoxHH; % pomocna premenna pre checkbox
        NumberOfRows;           % pocet riadkov nacitanych dat
        meanOfTimes;            
        stdOfTimes;   
        meanOfPackets;
        stdOfPackets;
        vstupneData;            % nazov suboru, ktory sa vlozil ako vstup

        importovaneData;        % data, ktore sa importuje do panelu pre upravovanie importu
        vystrihnuteData;        % data, ktore nam vznikli po uprave v paneli pre upravovanie importu

        fig;                    % aktualne pouzita figure (GUI menu)
        dataline;               % vykresluje hodnoty pri prepocitavani, aby uzivatel vedel kolko % je nacitanych
        
        pocet;
        okno;
        what_value;             % hodnota ktora urci co prepocitat
                                % app.what_value == 1 ->  fig = app.UIFigure;
                                % app.what_value == 2 ->  fig = app.UIFigureDetailed;

        what_casy_value;        % hodnota: z akeho suboru sa loadnu casy
                                % app.what_casy_value == 1 -> XLSX subor
                                % app.what_casy_value == 2 -> CSV subor
                                % app.what_casy_value == 3 -> MAT subor

        what_pcap_value;        % hodnota ci sa data nacitali z pcap suboru
                                % app.what_pcap_value == 1 -> je to pcap subor
                                % app.what_pcap_value == 0 -> nie je to pcap subor
        
        what_pakety_value;      % hodnota: z akeho suboru sa nacitali pakety
                                % app.what_pakety_value  == 1 -> XLSX subor
                                % app.what_pakety_value  == 2 -> CSV subor
                                % app.what_pakety_value  == 3 -> MAT subor

        % sloter parameters
        d;                      % timestamp variable
        val;                    % d z GUI sa sem ulozi
        a;                      % prirastky casu
        b;                      % prirastky paketov
        T,I,P;                  % pomocne parametre

        % Hurst 3D parametre
        vstup3D;
        filenameHurst3D,pathHurst3D;
        TimestampHurst3D;
       
    end
    
    properties (Access = public)
        % Hurst graf
        UIFigure2                   matlab.ui.Figure
        UIAxes_Hurst                matlab.ui.control.UIAxes
        Label_TextWindow            matlab.ui.control.Label
        Label_TextActualWindowValue matlab.ui.control.Label
        EditField_ChangeWindow      matlab.ui.control.NumericEditField  % Description
        PrepocitajHurst             matlab.ui.control.Button 

        % Detaielnejsi graf
        UIFigureDetailed                matlab.ui.Figure
        UIAxes_Detailed_Prirastky       matlab.ui.control.UIAxes
        Label_Pohyb_Odchylka            matlab.ui.control.Label
        stWindow                        matlab.ui.control.NumericEditField
        Lable_Pohyb_Priemer             matlab.ui.control.Label
        mWindow                         matlab.ui.control.NumericEditField
        Label_Pohyb_HH_DH               matlab.ui.control.Label
        sigmaWindow                     matlab.ui.control.NumericEditField
        Label_Zobrazit                  matlab.ui.control.Label
        CheckBox_KlzavyPriemer          matlab.ui.control.CheckBox
        CheckBox_KlzavaOdchylka         matlab.ui.control.CheckBox
        CheckBox_HHsigma                matlab.ui.control.CheckBox
        CheckBox_DHsigma                matlab.ui.control.CheckBox
        Prepocitaj_Details_Button       matlab.ui.control.Button
        Label_Sample_Window             matlab.ui.control.Label
        EditField_SampleWindow_In_detail    matlab.ui.control.NumericEditField


    end


    methods (Access = public)
        
        function Hurst_function_create(app, ~)
            % Create new UIFigure2 for methods
            app.UIFigure2 = uifigure('Visible', 'on');
            app.UIFigure2.Color = [0.5216 0.8549 1];
            app.UIFigure2.Position = [100 100 750 500];
            app.UIFigure2.Name = 'MATLAB App methods';
            app.UIFigure2.Scrollable = 'on';
            
            % Create UIAxes_Hurst
            app.UIAxes_Hurst = uiaxes(app.UIFigure2);
            title(app.UIAxes_Hurst, 'Hurst')
            xlabel(app.UIAxes_Hurst, 'X')
            ylabel(app.UIAxes_Hurst, 'Y')
            zlabel(app.UIAxes_Hurst, 'Z')
%             UIAxes_Hurst.ButtonDownFcn = createCallbackFcn(app, @PCAPfileMenuSelected, true);
            app.UIAxes_Hurst.Position = [20,470,650,450];

            % Create Label_Text_ChangeWindow
            app.Label_TextWindow = uilabel(app.UIFigure2);
            app.Label_TextWindow.Position = [10 440 100 22];
            app.Label_TextWindow.Text = 'Hodnota okna:';

            %Create Label_Text_ActualValueOfWindow
            app.Label_TextActualWindowValue = uilabel(app.UIFigure2);
            app.Label_TextActualWindowValue.Position = [400 440 300 22];
            app.Label_TextActualWindowValue.Text = 'Aktuálna hodnota okna:' + string(app.okno);

            % Create EditField_ChangeWindow
            app.EditField_ChangeWindow = uieditfield(app.UIFigure2, 'numeric');
            app.EditField_ChangeWindow.Limits = [0 Inf];
            app.EditField_ChangeWindow.HorizontalAlignment = 'left';
            app.EditField_ChangeWindow.Position = [110 440 100 22];
            %app.EditField_ChangeWindow.Value = 1;

            % Create PrepocitajHurst
            app.PrepocitajHurst = uibutton(app.UIFigure2, 'push');
            app.PrepocitajHurst.ButtonPushedFcn = createCallbackFcn(app, @HurstMenuSelected , true);
            app.PrepocitajHurst.BackgroundColor = [0.1804 0.6431 0.949];
            app.PrepocitajHurst.Position = [240 440 100 22];
            app.PrepocitajHurst.Text = 'Prepočítaj';
        end

        function DeailedInfo_function_create(app, ~) 
            % Create new UIFigure2 for methods
            app.UIFigureDetailed = uifigure('Visible', 'on');
            app.UIFigureDetailed.Color = [0.5216 0.8549 1];
            app.UIFigureDetailed.Position = [100 100 1250 700];
            app.UIFigureDetailed.Name = 'Detailnejší graf o prírastkoch času';
            app.UIFigureDetailed.Scrollable = 'on';
            
            % Create UIAxes_Detailed_Prirastky
            app.UIAxes_Detailed_Prirastky = uiaxes(app.UIFigureDetailed);
            title(app.UIAxes_Detailed_Prirastky, 'Prírastky paketov v čase');
            xlabel(app.UIAxes_Detailed_Prirastky, 'X');
            ylabel(app.UIAxes_Detailed_Prirastky, 'Y');
            zlabel(app.UIAxes_Detailed_Prirastky, 'Z');
            app.UIAxes_Detailed_Prirastky.NextPlot = 'add';
            app.UIAxes_Detailed_Prirastky.Position = [250,400,950,550];            

             % Create Label_Pohyb_Odchylka
            app.Label_Pohyb_Odchylka = uilabel(app.UIFigureDetailed);
            app.Label_Pohyb_Odchylka.HorizontalAlignment = 'right';
            app.Label_Pohyb_Odchylka.WordWrap = 'on';
            app.Label_Pohyb_Odchylka.FontSize = 12;
            app.Label_Pohyb_Odchylka.Position = [10 900 103 58];
            app.Label_Pohyb_Odchylka.Text = 'Veľkosť okna pre pohyblivú odchýlku:';

            % Create stWindow
            app.stWindow = uieditfield(app.UIFigureDetailed, 'numeric');
            app.stWindow.Limits = [1 Inf];
            app.stWindow.Position = [130 920 57 22];
            app.stWindow.Value = 10;
            app.stWindow.FontSize = 12;

            % Create Lable_Pohyb_Priemer
            app.Lable_Pohyb_Priemer = uilabel(app.UIFigureDetailed);
            app.Lable_Pohyb_Priemer.HorizontalAlignment = 'right';
            app.Lable_Pohyb_Priemer.WordWrap = 'on';
            app.Lable_Pohyb_Priemer.FontSize = 12;
            app.Lable_Pohyb_Priemer.Position = [10 850 102 58];
            app.Lable_Pohyb_Priemer.Text = 'Veľkosť okna pre pohyblivý priemer:';

            % Create mWindow
            app.mWindow = uieditfield(app.UIFigureDetailed, 'numeric');
            app.mWindow.Limits = [1 Inf];
            app.mWindow.FontSize = 12;
            app.mWindow.Position = [130 870 57 22];
            app.mWindow.Value = 10;

            % Create Label_Pohyb_HH_DH
            app.Label_Pohyb_HH_DH = uilabel(app.UIFigureDetailed);
            app.Label_Pohyb_HH_DH.HorizontalAlignment = 'right';
            app.Label_Pohyb_HH_DH.WordWrap = 'on';
            app.Label_Pohyb_HH_DH.FontSize = 12;
            app.Label_Pohyb_HH_DH.Position = [30 800 79 58];
            app.Label_Pohyb_HH_DH.Text = 'Veľkosť okna pre HH a DH sigmy:';

            % Create sigmaWindow
            app.sigmaWindow = uieditfield(app.UIFigureDetailed, 'numeric');
            app.sigmaWindow.Limits = [1 Inf];
            app.sigmaWindow.Position = [130 820 57 22];
            app.sigmaWindow.Value = 10;

            % Create Label_Zobrazit
            app.Label_Zobrazit = uilabel(app.UIFigureDetailed);
            app.Label_Zobrazit.FontWeight = 'bold';
            app.Label_Zobrazit.FontSize = 12;
            app.Label_Zobrazit.Position = [10 750 120 22];
            app.Label_Zobrazit.Text = 'Zobraziť na grafe:';

            % Create CheckBox_KlzavyPriemer
            app.CheckBox_KlzavyPriemer = uicheckbox(app.UIFigureDetailed);
            app.CheckBox_KlzavyPriemer.ValueChangedFcn = createCallbackFcn(app, @CheckBox_KlzavyPriemerValueChanged, true);
            app.CheckBox_KlzavyPriemer.Text = 'Kĺzavý priemer';
            app.CheckBox_KlzavyPriemer.FontSize = 12;
            app.CheckBox_KlzavyPriemer.Position = [20 720 150 22];
            app.CheckBox_KlzavyPriemer.Value = true;

            % Create CheckBox_KlzavaOdchylka
            app.CheckBox_KlzavaOdchylka = uicheckbox(app.UIFigureDetailed);
            app.CheckBox_KlzavaOdchylka.ValueChangedFcn = createCallbackFcn(app, @CheckBox_KlzavaOdchylkaValueChanged, true);
            app.CheckBox_KlzavaOdchylka.Text = 'Kĺzavá štandardná odchýlka';
            app.CheckBox_KlzavaOdchylka.WordWrap = 'on';
            app.CheckBox_KlzavaOdchylka.FontSize = 12;
            app.CheckBox_KlzavaOdchylka.Position = [20 685 200 39];
            app.CheckBox_KlzavaOdchylka.Value = true;

            % Create CheckBox_HHsigma
            app.CheckBox_HHsigma = uicheckbox(app.UIFigureDetailed);
            app.CheckBox_HHsigma.ValueChangedFcn = createCallbackFcn(app, @CheckBox_HHsigmaValueChanged, true);
            app.CheckBox_HHsigma.Text = 'HH sigma';
            app.CheckBox_HHsigma.FontSize = 12;
            app.CheckBox_HHsigma.Position = [20 670 100 22];
            app.CheckBox_HHsigma.Value = true;

            % Create CheckBox_DHsigma
            app.CheckBox_DHsigma = uicheckbox(app.UIFigureDetailed);
            app.CheckBox_DHsigma.ValueChangedFcn = createCallbackFcn(app, @CheckBox_DHsigmaValueChanged, true);
            app.CheckBox_DHsigma.Text = 'DH sigma';
            app.CheckBox_DHsigma.FontSize = 12;
            app.CheckBox_DHsigma.Position = [20 650 100 22];
            app.CheckBox_DHsigma.Value = true;

            % Create Prepocitaj_Details_Button
            app.Prepocitaj_Details_Button = uibutton(app.UIFigureDetailed, 'push');
            app.Prepocitaj_Details_Button.ButtonPushedFcn = createCallbackFcn(app, @Prepocitaj_Detail_Prirastky, true);
            app.Prepocitaj_Details_Button.BackgroundColor = [0.1804 0.6431 0.949];
            app.Prepocitaj_Details_Button.Position = [20 500 100 23];
            app.Prepocitaj_Details_Button.Text = 'Prepočítaj';

            % Create Label_Sample_Window
            app.Label_Sample_Window = uilabel(app.UIFigureDetailed);
            app.Label_Sample_Window.WordWrap = 'on';
            app.Label_Sample_Window.Position = [20 530 126 52];
            app.Label_Sample_Window.Text = 'Aktuálna veľkosť pre sample window';

            % Create EditField_SampleWindow_In_detail
            app.EditField_SampleWindow_In_detail = uieditfield(app.UIFigureDetailed, 'numeric');
            app.EditField_SampleWindow_In_detail.Limits = [0 Inf];
            app.EditField_SampleWindow_In_detail.HorizontalAlignment = 'left';
            app.EditField_SampleWindow_In_detail.Position = [120 530 100 22];
            app.EditField_SampleWindow_In_detail.Value = app.EditField_ChangeSampleWindow.Value;

        end

        function Sloter_function(app, ~)

            if app.what_value == 1
                temp_figure = app.fig;
                app.val = app.EditField_ChangeSampleWindow.Value;
                app.EditField_SampleWindow.Value = string(app.val);                
            end

            if app.what_value == 2
                temp_figure = app.UIFigureDetailed;
                app.val = app.EditField_SampleWindow_In_detail.Value;
                %app.EditField_SampleWindow_In_detail.Value = string(app.val);
            end
            
            if (app.what_casy_value == 1) || (app.what_casy_value == 2) || (app.what_casy_value == 3)
                app.Sloter_bez_paketov_function;
            else
                datalineSloter = uiprogressdlg(temp_figure,'Title','Slotovanie','Icon','membrane.png', ...
                    'Interpreter','html','Cancelable','on','CancelText','Cancel');
                datalineSloter.Message = 'Prebieha slotovanie vašich dát...';                

                app.d = app.val; %timeslot
                app.T = 0;
                app.I = 0;
                app.P = 0; 
                app.a = 0;  % prirastky casu
                app.b = 0;  % prirastky paketov      
                for i=1:app.N-1
                    app.T=app.T+app.te(i);
                    if mod(i,1000) == 0
                       datalineSloter.Value = i/app.N;
                       datalineSloter.Message = sprintf('Dokončené na %12.2f%%',i/app.N*100);
                       pause(0.05);
                    end
                    if app.T>app.d
                       app.a = [app.a , app.I];
                       app.b = [app.b , app.P];
                       app.I=1;
                       app.P=1;
                       app.T=app.T-app.d;
                       if app.T>app.d
                          app.I=0;
                          app.P=0;
                       end
                    else
                       app.I = app.I+1;
                       app.P = app.P + app.PacketLength(i);
                    end

                    if isempty(findobj(temp_figure)) == 1
                        datalineSloter.CancelRequested = 1;
                    end
                    if datalineSloter.CancelRequested == 1
                        disp('You Hit Cancel or Exited')
                        return
                    end
                end
                close(datalineSloter);
                app.Scale_a_2.Value = double(length(app.a));
                app.Scale_kB2.Value = double(length(app.b));  
            end
            
        end

        function Sloter_bez_paketov_function(app, ~)

            if app.what_value == 2
                temp_figure = app.UIFigureDetailed;
                app.val = app.EditField_SampleWindow_In_detail.Value;
                %app.EditField_SampleWindow_In_detail.Value = string(app.val);           
            else
                temp_figure = app.fig;
                app.val = app.EditField_ChangeSampleWindow.Value;
                app.EditField_SampleWindow.Value = string(app.val);
            end

            datalineSloterBezPaketov = uiprogressdlg(temp_figure,'Title','Slotovanie','Icon','membrane.png', ...
                'Interpreter','html','Cancelable','on','CancelText','Cancel');
            datalineSloterBezPaketov.Message = 'Prebieha slotovanie vašich dát...';
            
            app.d = app.val; %timeslot
            app.T = 0;
            app.I = 0;
            app.a = 0;  % prirastky casu    
            for i=1:app.N-1
                app.T=app.T+app.te(i);
                if mod(i,1000) == 0
                   datalineSloterBezPaketov.Value = i/app.N;
                   datalineSloterBezPaketov.Message = sprintf('Dokončené na %12.2f%%',i/app.N*100);
                   pause(0.05);
                end
                if app.T>app.d
                   app.a = [app.a , app.I];
                   app.I=1;
                   app.T=app.T-app.d;
                   if app.T>app.d
                      app.I=0;
                   end
                else
                   app.I = app.I+1;
                end

                if isempty(findobj(temp_figure)) == 1
                    datalineSloterBezPaketov.CancelRequested = 1;
                end
                if datalineSloterBezPaketov.CancelRequested == 1
                    disp('You Hit Cancel or Exited')
                    return
                end
            end
            close(datalineSloterBezPaketov);

            if app.what_casy_value ~= 0
                app.Scale_a_2.Value = double(length(app.a));
            elseif app.what_pakety_value ~= 0
                app.Scale_kB2.Value = double(length(app.a));
            end
            
        end


        function Sigma_function(app,~)
            cisty_priemer_pre_sigmu = 0;
            % vypocet obycajneho priemeru
            for k = 1 : length(app.a)
                cisty_priemer_pre_sigmu(k) = mean(app.a(k));
            end

            % ulozime transponovane
            app.a=app.a';

            % ----------- || VYPOCET SIGMY || -------------------
            sigmaWindowValue = app.sigmaWindow.Value;
            %sigma vypocet = sqrt((pow2(a-mean))/(N-1));
            app.pocet = 0;
            app.pocet = length(cisty_priemer_pre_sigmu);

            % vypocet sigmy hornej a dolnej hranice
            %dh = app.priemer - 0.5 * sigma;
            %hh = app.priemer + 0.5 * sigma;

            app.hh = 0;
            app.dh = 0;
            for j=1:app.pocet
                app.sigma = sqrt(((app.a(j)-cisty_priemer_pre_sigmu(app.pocet))^2)/(app.pocet-1));
                app.hh(j) = cisty_priemer_pre_sigmu(j) + sigmaWindowValue * app.sigma;
                app.dh(j) = cisty_priemer_pre_sigmu(j) - sigmaWindowValue * app.sigma;
            end
            % --------------------------------------------------
        end


        function Prepocitaj(app,~)
            
            if app.what_value == 1
                app.fig = app.UIFigure;
            elseif app.what_value == 2
                app.fig = app.UIFigureDetailed;
            end

            app.dataline = uiprogressdlg(app.fig,'Title','Čakajte prosím','Icon','membrane.png',...
                'Interpreter','html','Message','Opening the application');
			app.dataline.Message = 'Začiatok prepočítavania...';
            pause(.5)
        
            % Perform calculations
            %------------------GUI LOADER 33 percent-----------------------
            app.dataline.Value = .33; 
            app.dataline.Message = 'Prebieha načítavanie vašich dát...';
            pause(3)            

            if app.what_pcap_value == 1
                app.Timestamp = importdata('TimestampSkuska.mat');
                app.PacketLength = importdata('PacketSkuska.mat');
            else
                app.Timestamp = importdata('TimestampSkuska.mat');
            end
            

            % Perform calculations
            %------------------GUI LOADER 67 percent-----------------------
            app.dataline.Value = .67;
            app.dataline.Message = 'Prebieha prepočítavanie...';
            pause(1)

            % T2 je vektor s nasimi casmi z PCAP suboru
            % v N je ulozena velkost PCAP suboru
            % v meanOfTimes je priemer casov
            % v stdOfTimes je standardna odchylka
            app.T2 = 0;
            app.T2 = app.Timestamp;
            app.N = length(app.T2);

            % counting delta from data
            % funkcia diff robi deltu medzi casmi
            app.te = 0;
            app.te = diff(app.T2);
            app.Sloter_function;

            % ak mame otvorene detaielne zobrazenie prirastkov v case
            if app.what_value == 2
                % standardDeviation = posuvna funkcia so štandardnou odchýlkou
                % priemer = funkcia s posúvnym priemerom
                mWindowValue = app.mWindow.Value;
                stWindowValue = app.stWindow.Value;
                app.standardDeviation = movstd(app.a,[mWindowValue 0],'Endpoints','fill');
                app.priemer = movmean(app.a,[stWindowValue 0],'Endpoints','fill');
                app.Sigma_function;
            end
            

            % Finish recalculations
            %------------------GUI FINISHER 100 percent--------------------
            app.dataline.Value = 1;
            app.dataline.Message = 'Koniec prepočítavania';
            pause(2)
        
            % Close dialog box
            close(app.dataline)

            if app.what_value == 1
                % VYKRESLENIE GRAFOV po prepocitani s novym oknom
                app.x = app.T2(:,end);
                app.y = app.T2(:,end);
                plot(app.UIAxes_Kumulativ,app.T2);
                plot(app.UIAxes_Te,app.te);
                plot(app.UIAxes_KB,app.b);
                plot(app.UIAxes_Prirastky,app.a);
            elseif app.what_value == 2
                % VYKRESLENIE GRAFOV po prepocitani s novym oknom v
                % detaielnom zobrazeni prirastkov v case
                plot(app.UIAxes_Detailed_Prirastky,app.a);                
            end
            app.okno = 0;

        end

        function Prepocitaj_Detail_Prirastky(app,~)
            % cla mi premaze graf, aby sa mohol vykreslit novy
            cla(app.UIAxes_Detailed_Prirastky);
            app.what_value = 2;
            app.what_casy_value = 1;
            app.Prepocitaj;
        end

        function Nacitaj_Tabulku(app,~)
            tab = app.UITable;
            if (app.what_casy_value == 1) || (app.what_casy_value == 2) || (app.what_casy_value == 3)
                % vyplnenie tabulky s casmi
                tab.Data = [app.Timestamp];
                tab.ColumnFormat = {'long'};
                tab.ColumnName = {'Časy'};
            elseif (app.what_pakety_value == 1) || (app.what_pakety_value == 2) || (app.what_pakety_value == 3)
                tab.Data = [app.Timestamp];
                tab.ColumnFormat = {'long'};
                tab.ColumnName = {'Pakety'};
            elseif app.what_pcap_value == 1
                % vyplnenie tabulky s casmi a velkostami paketov
                tab.Data = [app.Timestamp app.PacketLength];
                tab.ColumnFormat = {'long' 'long'};
                tab.ColumnName = {'Časy'; 'Pakety'};
            elseif app.what_pcap_value == 0
                % vyplnenie tabulky s velkostami paketov
                tab.Data = [app.PacketLength];
                tab.ColumnFormat = {'long'};
            end
            app.okno = 0;
        end

        function NacitajVstupnySubor(app)

            if app.what_casy_value == 1 || app.what_pakety_value == 1
                [app.filename, app.path]=uigetfile('*.xlsx');
            elseif app.what_casy_value == 2 || app.what_pakety_value == 2
                [app.filename, app.path]=uigetfile('*.csv');
            elseif app.what_casy_value == 3 || app.what_pakety_value == 3
                [app.filename, app.path]=uigetfile('*.mat');
            elseif app.what_pcap_value == 1
                [app.filename, app.path]=uigetfile('*.pcap');
            end

            %if app.filename ~= 0 || app.path ~= 0
            if all(app.filename ~= 0)&&all(app.path ~= 0)
                if (app.what_pcap_value == 1)
                    app.NacitajPCAP;
                else
                    app.NacitajCasy;
                end
            else
                temp_fig = app.UIFigure;
                message = {'Nebol načítaný žiadny súbor!'};
                uialert(temp_fig,message,'Warning',...
                'Icon','warning');
            end
        end

        function NacitajPCAP(app,~)

            app.SetNameOfInputFileWithData;

            app.fig = app.UIFigure;
            app.dataline = uiprogressdlg(app.fig,'Title','Čakajte prosím','Icon','membrane.png',...
                'Interpreter','html','Message','Opening the application');
            pause(.5)
        
            % Perform calculations
            %------------------GUI LOADER 33 percent-----------------------
            app.dataline.Value = .33; 
            app.dataline.Message = 'Prebieha načítavanie vašich dát z PCAP súboru...';
            pause(1)
            
            %citanie PCAP suboru a rozparsovanie do tabulky
            pcapReaderObj = pcapReader(fullfile(app.path, app.filename),OutputTimestampFormat='microseconds');
            decodedPackets = readAll(pcapReaderObj);
            %pcapLength = length(decodedPackets);
            pcapAsTable = struct2table(decodedPackets);

            % ulozime si stlpec z tabulky pcapAsTable do vektoru Time
            app.Timestamp = pcapAsTable(:,2);
            Time = table2array(app.Timestamp);
            % ulozime si stlpec z tabulky pcapAsTable do vektoru Packet
            app.PacketLength = pcapAsTable(:,5);
            Packet = table2array(app.PacketLength);
        
            % Perform calculations
            %------------------GUI LOADER 67 percent-----------------------
            app.dataline.Value = .67;
            app.dataline.Message = 'Spracovanie dát do objektov...';
            pause(1)
        
            % Finish calculations
            %------------------GUI FINISHER 100 percent--------------------
            app.dataline.Value = 1;
            app.dataline.Message = 'Koniec';
            pause(1)
        
            % Close dialog box
            close(app.dataline)

            %ulozime si nase vektory: cas a velkost paketov
            save('TimestampSkuska','Time');
            save('PacketSkuska',"Packet");
        
            %naimportujeme si casy a pakety pre rychlejsiu pracu
            %[TimestampFile, path]=uigetfile('*.mat');
            %Timestamp = importdata(fullfile(path, TimestampFile));
            %[PacketsFile, path]=uigetfile('*.mat');
            %PacketLength = importdata(fullfile(path, PacketsFile));
            app.Timestamp = importdata('TimestampSkuska.mat');
            app.PacketLength = importdata("PacketSkuska.mat");
    
            % T2 je vektor s nasimi casmi z PCAP suboru
            % v N je ulozena velkost PCAP suboru
            % v meanOfTimes je priemer casov
            % v stdOfTimes je standardna odchylka
            app.T2 = app.Timestamp;

            app.N = 0;
            app.meanOfTimes = 0;
            app.meanOfPackets = 0;
            app.stdOfTimes = 0;

            app.N = length(app.T2);
            app.meanOfTimes = mean(app.T2);
            app.meanOfPackets = mean(app.PacketLength);
            app.stdOfTimes = std(app.T2);

            % counting delta from data
            % funkcia diff robi deltu medzi casmi
            app.te = diff(app.T2);
            medzery = app.te;
            save('Medzery','medzery');

            %Length of data
            app.NumberOfRows = app.N;
            %displaying the count of data
            app.EditField_LengthOfData.Value = string(app.NumberOfRows);
            app.Scale_Kumulativ2.Value = double(app.NumberOfRows);
            app.Scale_Te2.Value = double(app.NumberOfRows);
            app.EditField_MeanOfTimes.Value = string(app.meanOfTimes);
            app.EditField_MeanPackets.Value = string(app.meanOfPackets);
            app.EditField_StandardDeviation.Value = string(app.stdOfTimes);

            % funkcia pre vypocet prirastkov podla velkosti okna z GUI
            app.Sloter_function;
        
            app.x = app.T2(:,end);
            app.y = app.T2(:,end);
            plot(app.UIAxes_Kumulativ,app.T2);
            plot(app.UIAxes_Te,app.te);
            plot(app.UIAxes_KB,app.b);
            plot(app.UIAxes_Prirastky,app.a);

            app.Nacitaj_Tabulku;    
        end

        function NacitajCasy(app, ~)
            
            app.SetNameOfInputFileWithData;

            app.fig = app.UIFigure;
            app.d = uiprogressdlg(app.fig,'Title','Čakajte prosím','Icon','membrane.png',...
                'Interpreter','html','Message','Opening the application');
            pause(.5)
        
            % Perform calculations
            % ...
            app.d.Value = .33; 
            app.d.Message = 'Prebieha načítavanie vašich dát...';
            pause(1)


            if (app.what_casy_value == 3) || (app.what_pakety_value == 3)
                %naimportujeme si [casy/pakety v kB] z .mat suboru
                app.Timestamp = 0;
                app.T2 = 0;
                app.Timestamp = importdata(fullfile(app.path, app.filename));
                Time = app.Timestamp;
                app.T2 = app.Timestamp;
                save('TimestampSkuska','Time');                
                app.Timestamp = importdata('TimestampSkuska.mat');

                % Perform calculations
                % ...
                app.d.Value = .67;
                app.d.Message = 'Dáta sa spracovávajú...';
                pause(1)

                app.N = 0;
                app.meanOfTimes = 0;
                app.stdOfTimes = 0;
    
                app.N = length(app.T2);
                app.meanOfTimes = mean(app.T2);
                app.stdOfTimes = std(app.T2);

                % counting delta from data
                % funkcia diff robi deltu medzi casmi/velkostami paketov
                app.te = diff(app.T2);
    
                %Length of data
                app.NumberOfRows = app.N;
                %displaying the count of data
                app.EditField_LengthOfData.Value = string(app.NumberOfRows);
                %app.EditField_MeanOfTimes.Value = string(app.meanOfTimes);
                %app.EditField_StandardDeviation.Value = string(app.stdOfTimes);
                app.SetMeanInfoBasedOnLoadedData;

                app.Sloter_bez_paketov_function;    

                % nastavenie mierky pre kumulativne pocetnosti a medzery
                app.Scale_Te2.Value = double(app.NumberOfRows);  
                app.Scale_Kumulativ2.Value = double(app.NumberOfRows); 
                % vykreslenie grafov pre kumulativ, medzery a 
                % prirastky casov/prirastky paketov lebo mame 
                % app.what_casy_value == 3 alebo app.what_pakety_value == 3
                plot(app.UIAxes_Kumulativ,app.T2);
                plot(app.UIAxes_Te,app.te);
                % tu kontrolujeme ci sa vykreslia prirastky casu alebo
                % prirastky velkosti paketov
                if app.what_casy_value == 3
                    plot(app.UIAxes_Prirastky,app.a);
                elseif app.what_pakety_value == 3
                    plot(app.UIAxes_KB,app.a);
                end
                
            else
                %Nacitanie dat (casy/pakety v kB) z csv/xlsx
                data = readtable(fullfile(app.path, app.filename));
                app.Timestamp = table2array(data);
                app.T2 = app.Timestamp;
                Time = app.Timestamp;
                save('TimestampSkuska','Time');
                app.Timestamp = 0;
                app.Timestamp = importdata('TimestampSkuska.mat');

                % Perform calculations
                % ...
                app.d.Value = .67;
                app.d.Message = 'Dáta sa spracovávajú...';
                pause(1)              
            
                % Finish calculations
                % ...
                app.d.Value = 1;
                app.d.Message = 'Koniec';
                pause(1)
            
                % Close dialog box
                close(app.d)
    
                app.VypisDetailyODatach;
    
                app.Sloter_bez_paketov_function;
    
                app.x = app.T2(:,end);
                app.y = app.T2(:,end);    
                plot(app.UIAxes_Kumulativ,app.T2);
                plot(app.UIAxes_Te,app.te);
                % tu kontrolujeme ci sa vykreslia prirastky casu alebo
                % prirastky velkosti paketov
                if app.what_casy_value ~= 0
                    plot(app.UIAxes_Prirastky,app.a);
                elseif app.what_pakety_value ~= 0
                    plot(app.UIAxes_KB,app.a);
                end    
                app.okno = 0;
            end
            app.Nacitaj_Tabulku;
        end

        function VykresliHlavneGrafy(app, ~)
            app.x = app.T2(:,end);
            app.y = app.T2(:,end);

            plot(app.UIAxes_Kumulativ,app.T2);
            plot(app.UIAxes_Te,app.te);
            plot(app.UIAxes_KB,app.b);
            plot(app.UIAxes_Prirastky,app.a);
            app.VykresliDetailed_Prirastky;

            app.okno = 0;
        end

        function VykresliDetailed_Prirastky(app, ~)
            plot(app.UIAxes_Detailed_Prirastky,app.a);
        end

        function VypisDetailyODatach(app, ~)

            app.N = 0;
            app.meanOfTimes = 0;
            app.stdOfTimes = 0;

            app.N = length(app.T2);
            %mean of times
            app.meanOfTimes = mean(app.T2);
            %std of times
            app.stdOfTimes = std(app.T2);

            %counting delta from data
            app.te = diff(app.T2);

            %Length of data
            app.NumberOfRows = 0;
            app.NumberOfRows = app.N;
            %displaying the count of data
            app.EditField_LengthOfData.Value = string(app.NumberOfRows);
            app.Scale_Kumulativ2.Value = double(app.NumberOfRows);  
            app.Scale_Te2.Value = double(app.NumberOfRows);                     
            %app.EditField_MeanOfTimes.Value = string(app.meanOfTimes);            
            %app.EditField_StandardDeviation.Value = string(app.stdOfTimes);
            app.SetMeanInfoBasedOnLoadedData;
        end

        function SetNameOfInputFileWithData(app)         
            % app.vstupneData = nazov suboru vlozenych dat
            app.vstupneData = convertCharsToStrings(app.filename);
            app.EditField_NacitanySubor.Value = string(app.vstupneData);            
        end

        function SetMeanInfoBasedOnLoadedData(app)
            if app.what_casy_value ~= 0
                app.EditField_MeanOfTimes.Value = string(app.meanOfTimes);
                app.EditField_StandardDeviation.Value = string(app.stdOfTimes);
            elseif app.what_pakety_value ~= 0
                app.EditField_MeanPackets.Value = string(app.meanOfTimes);
                app.EditField_StandardDeviation_2.Value = string(app.stdOfTimes);
            end
            
        end

        function Hurst3D(app)
            app.HurstEditField_1.Value;
            app.HurstEditField_2.Value;
            app.HurstEditField_3.Value;
            app.HurstEditField_4.Value;
            app.HurstEditField_5.Value;
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UIAxesButtonDown(app, event)
         
        end

        % Callback function
        function MedzeryButtonPushed(app, event)
            %plot(app.UIAxes_Kumulativ,app.te);
            %title(app.UIAxes_Kumulativ, 'Medzery');
        end

        % Callback function
        function KumulatvneDataButtonPushed(app, event)
            %plot(app.UIAxes_Kumulativ,app.data1);
            %title(app.UIAxes_Kumulativ, 'Kumulatívne početnosti');
        end

        % Callback function
        function NavzorkovaneDataButtonPushed(app, event)
            %title(app.UIAxes_Kumulativ, 'Naslotované data');
        end

        % Button pushed function: Button_ChangeScale_Kumulativ
        function Button_ChangeScale_KumulativPushed(app, event)
            app.scaleFrom = app.Scale_Kumulativ1.Value;
            app.scaleTo = app.Scale_Kumulativ2.Value;
            plot(app.UIAxes_Kumulativ,app.T2(app.scaleFrom:app.scaleTo));
        end

        % Button pushed function: Button_ChangeScale_Te
        function Button_ChangeScale_TePushed(app, event)
            app.scaleFrom = app.Scale_Te1.Value;
            app.scaleTo = app.Scale_Te2.Value;
            plot(app.UIAxes_Te,app.te(app.scaleFrom:app.scaleTo));
        end

        % Button pushed function: Button_ChangeScale_kB
        function Button_ChangeScale_kBPushed(app, event)
            app.scaleFrom = app.Scale_kB1.Value;
            app.scaleTo = app.Scale_kB2.Value;
            plot(app.UIAxes_KB,app.a(app.scaleFrom:app.scaleTo));
        end

        % Callback function: PCAPsuborMenu, UIAxes_Kumulativ
        function PCAPfileMenuSelected(app, event)
            app.what_value = 1;
            app.what_pcap_value = 1;
            app.what_casy_value = 0;
            app.what_pakety_value = 0;
            app.NacitajVstupnySubor;
            
        end

        % Button pushed function: Button_ChangeScale_a
        function Button_ChangeScale_aPushed(app, event)
            app.scaleFrom = app.Scale_a_1.Value;
            app.scaleTo = app.Scale_a_2.Value;
            plot(app.UIAxes_Prirastky,app.a(app.scaleFrom:app.scaleTo));
            plot(app.UIAxes_Prirastky,app.standardDeviation(app.scaleFrom:app.scaleTo));
            plot(app.UIAxes_Prirastky,app.priemer(app.scaleFrom:app.scaleTo));
        end

        % Callback function
        function CheckBox_HHsigmaValueChanged(app, event)
            app.value = app.CheckBox_HHsigma.Value;
            if app.value == 1
                plot(app.UIAxes_Detailed_Prirastky,app.hh, Color='cyan');     
            else
                plot(app.UIAxes_Detailed_Prirastky,app.hh, Color='white');
            end
        end

        % Callback function
        function CheckBox_DHsigmaValueChanged(app, event)
            app.value = app.CheckBox_DHsigma.Value;
            if app.value == 1
                plot(app.UIAxes_Detailed_Prirastky,app.dh, Color='magenta');     
            else
                plot(app.UIAxes_Detailed_Prirastky,app.dh, Color='white');
            end            
        end

        % Callback function
        function CheckBox_KlzavaOdchylkaValueChanged(app, event)
            app.value = app.CheckBox_KlzavaOdchylka.Value;
            if app.value == 1
                plot(app.UIAxes_Detailed_Prirastky,app.standardDeviation, Color='green');     
            else
                plot(app.UIAxes_Detailed_Prirastky,app.standardDeviation, Color='white');
            end               
        end

        % Callback function
        function CheckBox_KlzavyPriemerValueChanged(app, event)
            app.value = app.CheckBox_KlzavyPriemer.Value;
            if app.value == 1
                plot(app.UIAxes_Detailed_Prirastky,app.priemer, Color='red');     
            else
                plot(app.UIAxes_Detailed_Prirastky,app.priemer, Color='white');
            end   
        end

        % Button pushed function: PrepocitajButton
        function PrepocitajButtonPushed(app, event)

            app.what_value = 1;             % toto nam urci ze sme zmackli prepocitaj button na hlavnej casti
            if app.what_pcap_value == 1
                app.what_pcap_value = 1;
                app.what_casy_value = 0;
                app.what_value = 1;
            elseif  app.what_casy_value == 1 || app.what_casy_value == 2 || app.what_casy_value == 3
                app.what_pcap_value = 0;
                app.what_casy_value = 1;
                app.what_value = 1;
            end
            app.Prepocitaj;            

        end

        % Menu selected function: HurstMenu
        function HurstMenuSelected(app, event)
            
            % nastavenie pociatocnej hodnoty velkosti okna pre vypocet Hursta
            % inak si ju vezme z GUIky
            if exist('app.EditField_ChangeWindow','var')
                app.okno = app.EditField_ChangeWindow.Value;
            else
                app.okno = 1000;
            end
            
        % funkcia pre vytvorenie noveho okna s grafom pre vypocet Hursta
            app.Hurst_function_create;

            temp_figure = app.UIFigure2;

            datalineHurst = uiprogressdlg(temp_figure,'Title','Hurst','Icon','membrane.png', ...
                'Interpreter','html','Cancelable','on','CancelText','Cancel');
            datalineHurst.Message = 'Prebieha výpočet Hurstovho koeficientu...';  

            app.Timestamp = importdata('TimestampSkuska.mat');
            Nt = app.Timestamp;
            app.N = length(Nt);

            %-------------- pocitanie Hursta ------------------------
                       
            for i=1:app.N-app.okno
                if mod(i,1000) == 0
                   datalineHurst.Value = i/app.N;
                   datalineHurst.Message = sprintf('Dokončené na %12.2f%%',i/app.N*100);
                   pause(0.05);
                   i
                end
                data = Nt(i:app.okno-1+i);
                H(i) = hurst(data);
                if isempty(findobj(temp_figure)) == 1
                    datalineHurst.CancelRequested = 1;
                end
                if datalineHurst.CancelRequested == 1
                    disp('You Hit Cancel or Exited')
                    return
                end
            end
            close(datalineHurst);
    
            % vykreslenie grafu
            xx = linspace(app.okno,app.N,app.N-app.okno);
            plot(app.UIAxes_Hurst ,xx,H);

             %-------------- koniec pocitania Hursta ----------------
          

        end

        % Menu selected function: DetaielnejsiVystup
        function DetaielnejsiVystupMenuSelected(app, event)
            app.what_value = 2;
            app.what_casy_value = 1;
            %vytvorenie UI pre detaielnejsi vystup o prirastkoch
            app.DeailedInfo_function_create;

            % nacitanie dat
            app.Timestamp = importdata('TimestampSkuska.mat');
            %app.PacketLength = importdata("PacketSkuska.mat");
    
            % T2 je vektor s nasimi casmi z PCAP suboru
            % v N je ulozena velkost PCAP suboru
            % v meanOfTimes je priemer casov
            % v stdOfTimes je standardna odchylka
            app.T2 = app.Timestamp;
            app.N = length(app.T2);

            % counting delta from data
            % funkcia diff robi deltu medzi casmi
            app.te = diff(app.T2);

            % funkcia pre vypocet prirastkov podla velkosti okna z GUI
            app.Sloter_function;

            % standardDeviation = posuvna funkcia so štandardnou odchýlkou
            % priemer = funkcia s posúvnym priemerom
            mWindowValue = app.mWindow.Value;
            stWindowValue = app.stWindow.Value;
            app.standardDeviation = movstd(app.a,[mWindowValue 0],'Endpoints','fill');
            app.priemer = movmean(app.a,[stWindowValue 0],'Endpoints','fill');

            app.Sigma_function;

            plot(app.UIAxes_Detailed_Prirastky,app.a);
            plot(app.UIAxes_Detailed_Prirastky,app.standardDeviation, Color='green');
            plot(app.UIAxes_Detailed_Prirastky,app.priemer, Color='red');
            plot(app.UIAxes_Detailed_Prirastky,app.dh, Color='magenta');
            plot(app.UIAxes_Detailed_Prirastky,app.hh, Color='cyan');
            app.okno = 0;


        end

        % Menu selected function: XLSX_suborMenu
        function XLSX_suborMenuMenuSelected(app, event)
            app.what_casy_value = 1;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_pakety_value = 0;
            app.NacitajVstupnySubor;
        end

        % Menu selected function: CSV_suborMenu
        function CSV_suborMenuSelected(app, event)
            app.what_casy_value = 2;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_pakety_value = 0;
            app.NacitajVstupnySubor;
        end

        % Menu selected function: MAT_suborMenu
        function MAT_suborMenuSelected(app, event)
            app.what_casy_value = 3;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_pakety_value = 0;
            app.NacitajVstupnySubor;
        end

        % Menu selected function: UpravaVstupu
        function UpravaVstupuMenuSelected(app, event)
            app.Panel_UpravaVstupu.Visible = "on";            
        end

        % Button pushed function: UkoniButton
        function UkoniButtonPushed(app, event)
            app.Panel_UpravaVstupu.Visible = "off";
        end

        % Button pushed function: UlozkBPrirastkyButton
        function UlozkBPrirastkyButtonPushed(app, event)
           
            prirastky_kB = app.b;
            % uigetdir nam umozni si vybrat folder pre ulozenie
            kB = uigetdir;
            if kB ~= 0
                path_as_string = convertCharsToStrings(kB);
                tempName = app.vstupneData;
                tempOkno = string(app.d);
                path_as_string = path_as_string + "\prirastky_kB_" + tempName + "_sOknom_" + tempOkno + ".mat";
                save(path_as_string,'prirastky_kB');  
            else
                %errordlg('Nevybrali ste priečinok pre uloženie prírastkov paketov v kB...','Error');
                temp_fig = app.UIFigure;
                message = {'Nevybrali ste priečinok pre uloženie prírastkov paketov v kB...'};
                uialert(temp_fig,message,'Warning',...
                'Icon','warning');
            end                      

        end

        % Button pushed function: UlozPrirastkyCasuButton
        function UlozPrirastkyCasuButtonPushed(app, event)
            
            prirastky_casu = app.a;
            % uigetdir nam umozni si vybrat folder pre ulozenie
            cas = uigetdir;
            if cas ~= 0
                path_as_string = convertCharsToStrings(cas);
                tempName = app.vstupneData;
                tempOkno = string(app.d);
                path_as_string = path_as_string + "\prirastky_casu_" + tempName + "_sOknom_" + tempOkno + ".mat";
                save(path_as_string,'prirastky_casu');                   
            else
                %errordlg('Nevybrali ste priečinok pre uloženie prírastkov času...','Error');
                temp_fig = app.UIFigure;
                message = {'Nevybrali ste priečinok pre uloženie prírastkov času...'};
                uialert(temp_fig,message,'Warning',...
                'Icon','warning');
            end
            
        end

        % Button pushed function: NacitatVstupButton
        function NacitatVstupButtonPushed(app, event)
            
            [app.filename, app.path]=uigetfile('*.mat');
            temp_path = fullfile(app.path, app.filename);
            if ( length(temp_path) > 3) 
                app.importovaneData = importdata(temp_path);
                plot(app.Panel_UIAxes,app.importovaneData); 
                app.Panel_StrihajPo.Value = double(length(app.importovaneData));
            else
                %errordlg('Súbor sa nenašiel...','Error');
                temp_fig = app.UIFigure;
                message = {'Súbor sa nenašiel...'};
                uialert(temp_fig,message,'Warning',...
                'Icon','warning');
            end

        end

        % Button pushed function: VystrihniButton
        function VystrihniButtonPushed(app, event)
           
            app.scaleFrom = app.Panel_StrihajOd.Value;
            app.scaleTo = app.Panel_StrihajPo.Value;
            app.vystrihnuteData = app.importovaneData(app.scaleFrom:app.scaleTo);
           
            % ideme si ulozit tie nove vystrihnute data
            vystrihnute_prirastky = app.vystrihnuteData;
            % uigetdir nam umozni si vybrat folder pre ulozenie
            priecinok = uigetdir;
            path_as_string = convertCharsToStrings(priecinok);
            tempName = app.vstupneData;
            path_as_string = path_as_string + "\" + "vystrihnute_prirastky_" + tempName + ".mat";
%             path_as_string = path_as_string + "\" + "vystrihnute_prirastky_" + ".mat";
            save(path_as_string,'vystrihnute_prirastky'); 
           

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            cla(app.UIAxes_KB);
            cla(app.UIAxes_Prirastky);
            cla(app.UIAxes_Te);
            cla(app.UIAxes_Kumulativ);
            app.HurstEditField_1.Value = '0';

            app.EditField_NacitanySubor.Value = 'žiadny';
            app.EditField_LengthOfData.Value = '0';
            app.EditField_MeanOfTimes.Value = '0';
            app.EditField_MeanPackets.Value = '0';
            app.EditField_StandardDeviation.Value = '0';
            app.EditField_StandardDeviation_2.Value = '0';
            app.EditField_SampleWindow.Value = '3000';
            app.EditField_ChangeSampleWindow.Value = 1;
            
            app.Scale_Kumulativ2.Value = 1000;
            app.Scale_Te2.Value = 1000;
            app.Scale_kB2.Value = 1000;
            app.Scale_a_2.Value = 1000;

            tab = app.UITable;
            tab.Data = [];

        end

        % Button pushed function: UlozPacketLengthButton
        function UlozPacketLengthButtonPushed(app, event)
            velkosti_paketov = app.PacketLength;
            % uigetdir nam umozni si vybrat folder pre ulozenie
            pakety = uigetdir;
            if pakety ~= 0
                path_as_string = convertCharsToStrings(pakety);
                tempName = app.vstupneData;
                path_as_string = path_as_string + "\velkosti_paketov" + tempName + ".mat";
                save(path_as_string,'velkosti_paketov');     
            else
                %errordlg('Nevybrali ste priečinok pre uloženie veľkosti paketov...','Error');
                temp_fig = app.UIFigure;
                message = {'Nevybrali ste priečinok pre uloženie veľkosti paketov...'};
                uialert(temp_fig,message,'Warning',...
                'Icon','warning');
            end                      
        end

        % Button pushed function: UlozTimestampButton
        function UlozTimestampButtonPushed(app, event)
            prichody_casov = app.Timestamp;
            % uigetdir nam umozni si vybrat folder pre ulozenie
            cas = uigetdir;
            if cas ~= 0
                path_as_string = convertCharsToStrings(cas);
                tempName = app.vstupneData;
                path_as_string = path_as_string + "\prichody_casov_" + tempName + ".mat";
                save(path_as_string,'prichody_casov');   
            else
                %errordlg('Nevybrali ste priečinok pre uloženie časov...','Error');
                temp_fig = app.UIFigure;
                message = {'Nevybrali ste priečinok pre uloženie časov...'};
                uialert(temp_fig,message,'Warning',...
                'Icon','warning');
            end
            
        end

        % Menu selected function: XLSX_paketyMenu
        function XLSX_paketyMenuSelected(app, event)
            app.what_pakety_value = 1;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_casy_value = 0;
            app.NacitajVstupnySubor;
            
        end

        % Menu selected function: CSV_paketyMenu
        function CSV_paketyMenuSelected(app, event)
            app.what_pakety_value = 2;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_casy_value = 0;
            app.NacitajVstupnySubor;
        end

        % Menu selected function: MAT_paketyMenu
        function MAT_paketyMenuSelected(app, event)
            app.what_pakety_value = 3;
            app.what_value = 1;
            app.what_pcap_value = 0;
            app.what_casy_value = 0;
            app.NacitajVstupnySubor;
        end

        % Menu selected function: Hurst3DMenu
        function Hurst3DMenuSelected(app, event)
            app.Panel_Hurst3D.Visible = "on";  
        end

        % Button pushed function: ButtonUkonciHurst3D
        function ButtonUkonciHurst3DPushed(app, event)
            app.Panel_Hurst3D.Visible = "off";  
        end

        % Button pushed function: ButtonHurstNacitatSubor
        function ButtonHurstNacitatSuborPushed(app, event)
            [app.filenameHurst3D, app.pathHurst3D]=uigetfile('*.mat');               

            if all(app.filenameHurst3D ~= 0)&&all(app.pathHurst3D ~= 0)
                app.pathHurst3D = fullfile(app.pathHurst3D, app.filenameHurst3D);
                app.vstup3D = importdata(app.pathHurst3D);
                nacitany3DVstup = convertCharsToStrings(app.pathHurst3D);
                app.LabelHurst3DVstup.Text = string(nacitany3DVstup);   
            else
                temp_fig = app.UIFigure;
                message = {'Nebol načítaný žiadny súbor!'};
                uialert(temp_fig,message,'Warning',...
                'Icon','warning');
            end

        end

        % Button pushed function: ButtonResetovat
        function ButtonResetovatPushed(app, event)
            cla(app.UIAxesHurst3D);
            app.LabelHurst3DVstup.Text = 'žiadny';
            app.EditField_Hurst3D_1.Value = 0;
            app.EditField_Hurst3D_2.Value = 0;
            app.EditField_Hurst3D_3.Value = 0;
            app.EditField_Hurst3D_4.Value = 0;
            app.EditField_Hurst3D_5.Value = 0;            
            app.pathHurst3D = 0;
            app.filenameHurst3D = 0;
            app.vstup3D = [];
           
        end

        % Button pushed function: ButtonVypocetHurst3D
        function ButtonVypocetHurst3DPushed(app, event)
            
            temp_figure = app.UIFigure;
            if all(app.filenameHurst3D ~= 0)&&all(app.pathHurst3D ~= 0)                

                app.TimestampHurst3D = app.vstup3D;
                Nt = app.TimestampHurst3D;
                N_Hurst3D = length(Nt);                
            
                datalineHurst3D = uiprogressdlg(temp_figure,'Title','Hurst 3D','Icon','membrane.png', ...
                    'Interpreter','html','Cancelable','on','CancelText','Cancel');
                datalineHurst3D.Message = 'Prebieha výpočet Hurstovho koeficientu...';  
            
                %-------------- pocitanie Hursta ------------------------
            
                colors_array = ["red" "green" "blue" "cyan" "magenta"];
                positions_colors = [];
                okna_correct = [];

                val1 = app.EditField_Hurst3D_1.Value;
                val2 = app.EditField_Hurst3D_2.Value;
                val3 = app.EditField_Hurst3D_3.Value;
                val4 = app.EditField_Hurst3D_4.Value;
                val5 = app.EditField_Hurst3D_5.Value;

                okna = [val1 val2 val3 val4 val5];     
                pocetOkien = length(okna);
                for o=1:pocetOkien
                    if okna(o) ~= 0
                        okna_correct(end+1) = okna(o);
                        positions_colors(end+1) = o;
                    end
                end

                pocetOkien = length(okna_correct);
                okna = okna_correct;
                okna = sort(okna);
                size_xx = [];
                H_table = table();

                for j=1:pocetOkien
                    for i=1:N_Hurst3D-okna(j)
                        if mod(i,1000) == 0
                           datalineHurst3D.Value = i/N_Hurst3D;
                           datalineHurst3D.Message = sprintf('(%d/%d) Dokončené na %12.2f%%',j,pocetOkien,i/N_Hurst3D*100);
                           pause(0.05);
                        end
                        data = Nt(i:okna(j)-1+i);
                        H(i) = hurst(data);
                        if isempty(findobj(temp_figure)) == 1
                            datalineHurst3D.CancelRequested = 1;
                        end
                        if datalineHurst3D.CancelRequested == 1
                            disp('You Hit Cancel or Exited')
                            return
                        end
                    end
                    H_table = addvars(H_table,H);
                    xx = linspace(okna(j),N_Hurst3D,N_Hurst3D-okna(j));
                    size_xx(end+1) = length(xx);
                    if j == 1
                        Temp_T = table(xx);
                    else
                        Temp_T = addvars(Temp_T,xx,'After',j-1);
                    end
                end
                close(datalineHurst3D);
            
                maxValue_xx = max(size_xx);
                Temp_T2 = table();
                numberOfColumns = width(Temp_T);
                for c=1:numberOfColumns
                    temp_xx = table2array(Temp_T(:,c));
                    if length(temp_xx) < maxValue_xx
                        temp_xx = [zeros(1,maxValue_xx-length(temp_xx)),temp_xx];
                        Temp_T2 = addvars(Temp_T2,temp_xx);
                    else
                        Temp_T2 = addvars(Temp_T2,temp_xx);
                    end
                    clear temp_xx;
                end
            
                % to set ax.View is necessary when you want to plot3 correctly
                ax = app.UIAxesHurst3D;
                ax.View = [1 3];
                t  = linspace(1,maxValue_xx,maxValue_xx); 
                for h=1:width(H_table)
                    HH = table2array(H_table(:,h));
                    tw = h*ones(1,maxValue_xx);        
                    col = positions_colors(h);
                    plot3(ax,t,tw,HH,'-',Color=colors_array(col));  
                    hold(ax,"on")
                end

            else
                message = {'Nebol načítaný žiadny súbor!'};
                uialert(temp_fig,message,'Warning',...
                'Icon','warning');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VstupyMenu = uimenu(app.UIFigure);
            app.VstupyMenu.Text = 'Vstupy';
            app.CasyMenu = uimenu(app.VstupyMenu);
            app.CasyMenu.Text = 'Čas z';
            app.XLSX_suborMenu = uimenu(app.CasyMenu);
            app.XLSX_suborMenu.MenuSelectedFcn = createCallbackFcn(app, @XLSX_suborMenuMenuSelected, true);
            app.XLSX_suborMenu.Text = '.xlsx subor';
            app.CSV_suborMenu = uimenu(app.CasyMenu);
            app.CSV_suborMenu.MenuSelectedFcn = createCallbackFcn(app, @CSV_suborMenuSelected, true);
            app.CSV_suborMenu.Text = '.csv subor';
            app.MAT_suborMenu = uimenu(app.CasyMenu);
            app.MAT_suborMenu.MenuSelectedFcn = createCallbackFcn(app, @MAT_suborMenuSelected, true);
            app.MAT_suborMenu.Text = '.mat subor';
            app.PaketyzCSVMenu = uimenu(app.VstupyMenu);
            app.PaketyzCSVMenu.Text = 'Pakety z CSV';
            app.XLSX_paketyMenu = uimenu(app.PaketyzCSVMenu);
            app.XLSX_paketyMenu.MenuSelectedFcn = createCallbackFcn(app, @XLSX_paketyMenuSelected, true);
            app.XLSX_paketyMenu.Text = '.xlsx subor';
            app.CSV_paketyMenu = uimenu(app.PaketyzCSVMenu);
            app.CSV_paketyMenu.MenuSelectedFcn = createCallbackFcn(app, @CSV_paketyMenuSelected, true);
            app.CSV_paketyMenu.Text = '.csv subor';
            app.MAT_paketyMenu = uimenu(app.PaketyzCSVMenu);
            app.MAT_paketyMenu.MenuSelectedFcn = createCallbackFcn(app, @MAT_paketyMenuSelected, true);
            app.MAT_paketyMenu.Text = '.mat subor';
            app.PCAPsuborMenu = uimenu(app.VstupyMenu);
            app.PCAPsuborMenu.MenuSelectedFcn = createCallbackFcn(app, @PCAPfileMenuSelected, true);
            app.PCAPsuborMenu.Text = 'PCAP súbor';
            app.FunkcieMenu = uimenu(app.UIFigure);
            app.FunkcieMenu.Text = 'Funkcie';
            app.HurstMenu = uimenu(app.FunkcieMenu);
            app.HurstMenu.MenuSelectedFcn = createCallbackFcn(app, @HurstMenuSelected, true);
            app.HurstMenu.Text = 'Hurst';
            app.Hurst3DMenu = uimenu(app.FunkcieMenu);
            app.Hurst3DMenu.MenuSelectedFcn = createCallbackFcn(app, @Hurst3DMenuSelected, true);
            app.Hurst3DMenu.Text = 'Hurst 3D';
            app.AR1Menu = uimenu(app.FunkcieMenu);
            app.AR1Menu.Text = 'AR1';
            app.KoeficientvariabilnostiMenu = uimenu(app.FunkcieMenu);
            app.KoeficientvariabilnostiMenu.Text = 'Koeficient variabilnosti';
            app.DetaielnejsiVystup = uimenu(app.FunkcieMenu);
            app.DetaielnejsiVystup.MenuSelectedFcn = createCallbackFcn(app, @DetaielnejsiVystupMenuSelected, true);
            app.DetaielnejsiVystup.Text = 'Detailnejší výstup';
            app.UpravaVstupu = uimenu(app.FunkcieMenu);
            app.UpravaVstupu.MenuSelectedFcn = createCallbackFcn(app, @UpravaVstupuMenuSelected, true);
            app.UpravaVstupu.Text = 'Upravenie vstupu pre IMPORT';
            app.UIAxes_Kumulativ = uiaxes(app.UIFigure);
            title(app.UIAxes_Kumulativ, 'Kumulatívne medzery medzi paketmi')
            xlabel(app.UIAxes_Kumulativ, 'X')
            ylabel(app.UIAxes_Kumulativ, 'Y')
            zlabel(app.UIAxes_Kumulativ, 'Z')
            app.UIAxes_Kumulativ.ButtonDownFcn = createCallbackFcn(app, @PCAPfileMenuSelected, true);
            app.UIAxes_Te = uiaxes(app.UIFigure);
            title(app.UIAxes_Te, 'Medzery medzi paketmi')
            xlabel(app.UIAxes_Te, 'X')
            ylabel(app.UIAxes_Te, 'Y')
            zlabel(app.UIAxes_Te, 'Z')
            app.UIAxes_KB = uiaxes(app.UIFigure);
            title(app.UIAxes_KB, 'Prírastky informácií v kB')
            xlabel(app.UIAxes_KB, 'X')
            ylabel(app.UIAxes_KB, 'Y')
            zlabel(app.UIAxes_KB, 'Z')
            app.UIAxes_Prirastky = uiaxes(app.UIFigure);
            title(app.UIAxes_Prirastky, 'Prírastky paketov v čase')
            xlabel(app.UIAxes_Prirastky, 'X')
            ylabel(app.UIAxes_Prirastky, 'Y')
            zlabel(app.UIAxes_Prirastky, 'Z')
            app.Scale_Kumulativ2 = uieditfield(app.UIFigure, 'numeric');
            app.Scale_Kumulativ2.Limits = [1 Inf];
            app.Scale_Kumulativ2.ValueDisplayFormat = '%.0f';
            app.Scale_Kumulativ2.Value = 1000;
            app.Button_ChangeScale_Kumulativ = uibutton(app.UIFigure, 'push');
            app.Button_ChangeScale_Kumulativ.ButtonPushedFcn = createCallbackFcn(app, @Button_ChangeScale_KumulativPushed, true);
            app.Button_ChangeScale_Kumulativ.Text = 'Nastav mierku';
            app.Scale_Kumulativ1 = uieditfield(app.UIFigure, 'numeric');
            app.Scale_Kumulativ1.Limits = [1 Inf];
            app.Scale_Kumulativ1.ValueDisplayFormat = '%.0f';
            app.Scale_Kumulativ1.Value = 1;
            app.Scale_Te1 = uieditfield(app.UIFigure, 'numeric');
            app.Scale_Te1.Limits = [1 Inf];
            app.Scale_Te1.ValueDisplayFormat = '%.0f';
            app.Scale_Te1.Value = 1;
            app.Label_Te = uilabel(app.UIFigure);
            app.Label_Te.Text = ':';
            app.Scale_Te2 = uieditfield(app.UIFigure, 'numeric');
            app.Scale_Te2.Limits = [1 Inf];
            app.Scale_Te2.ValueDisplayFormat = '%.0f';
            app.Scale_Te2.Value = 1000;
            app.Button_ChangeScale_Te = uibutton(app.UIFigure, 'push');
            app.Button_ChangeScale_Te.ButtonPushedFcn = createCallbackFcn(app, @Button_ChangeScale_TePushed, true);
            app.Button_ChangeScale_Te.Text = 'Nastav mierku';
            app.Label_Kumulativ = uilabel(app.UIFigure);
            app.Label_Kumulativ.Text = ':';
            app.Scale_kB1 = uieditfield(app.UIFigure, 'numeric');
            app.Scale_kB1.Limits = [1 Inf];
            app.Scale_kB1.ValueDisplayFormat = '%.0f';
            app.Scale_kB1.Value = 1;
            app.Label_Te_2 = uilabel(app.UIFigure);
            app.Label_Te_2.Text = ':';
            app.Scale_kB2 = uieditfield(app.UIFigure, 'numeric');
            app.Scale_kB2.Limits = [1 Inf];
            app.Scale_kB2.ValueDisplayFormat = '%.0f';
            app.Scale_kB2.Value = 1000;
            app.Button_ChangeScale_kB = uibutton(app.UIFigure, 'push');
            app.Button_ChangeScale_kB.ButtonPushedFcn = createCallbackFcn(app, @Button_ChangeScale_kBPushed, true);
            app.Button_ChangeScale_kB.Text = 'Nastav mierku';
            app.PriemerLabel = uilabel(app.UIFigure);
            app.PriemerLabel.Text = 'Priemer časov (mikrosekundy):';
            app.tandardnodchlkaLabel = uilabel(app.UIFigure);
            app.tandardnodchlkaLabel.Text = 'Štandardná odchýlka časov:';
            app.PriemerLabel_2 = uilabel(app.UIFigure);
            app.PriemerLabel_2.Text = 'Priemer paketov:';
            app.tandardnodchlkaLabel_3 = uilabel(app.UIFigure);
            app.tandardnodchlkaLabel_3.Text = 'Štandardná odchýlka paketov:';
            app.Scale_a_1 = uieditfield(app.UIFigure, 'numeric');
            app.Scale_a_1.Limits = [1 Inf];
            app.Scale_a_1.ValueDisplayFormat = '%.0f';
            app.Scale_a_1.Value = 1;
            app.Label_Te_3 = uilabel(app.UIFigure);
            app.Label_Te_3.Text = ':';
            app.tandardnodchlkaLabel_2 = uilabel(app.UIFigure);
            app.tandardnodchlkaLabel_2.Text = 'Default veľkosť sample window:';
            app.Scale_a_2 = uieditfield(app.UIFigure, 'numeric');
            app.Scale_a_2.Limits = [1 Inf];
            app.Scale_a_2.ValueDisplayFormat = '%.0f';
            app.Scale_a_2.Value = 1000;
            app.Button_ChangeScale_a = uibutton(app.UIFigure, 'push');
            app.Button_ChangeScale_a.ButtonPushedFcn = createCallbackFcn(app, @Button_ChangeScale_aPushed, true);
            app.Button_ChangeScale_a.Text = 'Nastav mierku';
            app.EditField_ChangeSampleWindow = uieditfield(app.UIFigure, 'numeric');
            app.EditField_ChangeSampleWindow.Limits = [0 Inf];
            app.EditField_ChangeSampleWindow.Value = 1;
            app.EditField_SampleWindow = uieditfield(app.UIFigure, 'text');
            app.EditField_SampleWindow.Placeholder = '0';
            app.EditField_SampleWindow.Value = '3000';
            app.EditField_StandardDeviation_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_StandardDeviation_2.Placeholder = '0';
            app.EditField_StandardDeviation = uieditfield(app.UIFigure, 'text');
            app.EditField_StandardDeviation.Placeholder = '0';
            app.EditField_MeanPackets = uieditfield(app.UIFigure, 'text');
            app.EditField_MeanPackets.Placeholder = '0';
            app.EditField_MeanOfTimes = uieditfield(app.UIFigure, 'text');
            app.EditField_MeanOfTimes.Placeholder = '0';
            app.NovvekospresamplewindowLabel = uilabel(app.UIFigure);
            app.NovvekospresamplewindowLabel.Text = 'Aktuálna veľkosť pre sample window';
            app.VekosnatanchdtLabel = uilabel(app.UIFigure);
            app.VekosnatanchdtLabel.Text = 'Veľkosť načítaných dát:';
            app.EditField_LengthOfData = uieditfield(app.UIFigure, 'text');
            app.EditField_LengthOfData.Placeholder = '0';
            app.EditField_NacitanySubor = uieditfield(app.UIFigure, 'text');
            app.EditField_NacitanySubor.Value = 'žiadny';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Časy'; 'Pakety'};
            app.PrepocitajButton = uibutton(app.UIFigure, 'push');
            app.PrepocitajButton.ButtonPushedFcn = createCallbackFcn(app, @PrepocitajButtonPushed, true);
            app.PrepocitajButton.Text = 'Prepočítať';
            app.Panel_UpravaVstupu = uipanel(app.UIFigure);
            app.Panel_UpravaVstupu.Title = 'Úprava vstupu pre nový IMPORT';
            app.Panel_UpravaVstupu.Visible = 'off';
            app.Panel_UIAxes = uiaxes(app.Panel_UpravaVstupu);
            title(app.Panel_UIAxes, 'Prírastky pre spracovanie')
            xlabel(app.Panel_UIAxes, 'X')
            ylabel(app.Panel_UIAxes, 'Y')
            zlabel(app.Panel_UIAxes, 'Z')
            app.VystrihniButton = uibutton(app.Panel_UpravaVstupu, 'push');
            app.VystrihniButton.ButtonPushedFcn = createCallbackFcn(app, @VystrihniButtonPushed, true);
            app.VystrihniButton.Text = 'Vystrihnúť';
            app.Panel_StrihajOd = uieditfield(app.Panel_UpravaVstupu, 'numeric');
            app.Panel_StrihajOd.Limits = [1 Inf];
            app.Panel_StrihajOd.ValueDisplayFormat = '%.0f';
            app.Panel_StrihajOd.Value = 1;
            app.Panel_dvojbodka = uilabel(app.Panel_UpravaVstupu);
            app.Panel_dvojbodka.Text = ':';
            app.Panel_StrihajPo = uieditfield(app.Panel_UpravaVstupu, 'numeric');
            app.Panel_StrihajPo.Limits = [1 Inf];
            app.Panel_StrihajPo.ValueDisplayFormat = '%.0f';
            app.Panel_StrihajPo.Value = 1000000;
            app.UkoniButton = uibutton(app.Panel_UpravaVstupu, 'push');
            app.UkoniButton.ButtonPushedFcn = createCallbackFcn(app, @UkoniButtonPushed, true);
            app.UkoniButton.Text = 'Ukončiť';
            app.NacitatVstupButton = uibutton(app.Panel_UpravaVstupu, 'push');
            app.NacitatVstupButton.ButtonPushedFcn = createCallbackFcn(app, @NacitatVstupButtonPushed, true);
            app.NacitatVstupButton.Text = 'Načítať vstup';
            app.UlozkBPrirastkyButton = uibutton(app.UIFigure, 'push');
            app.UlozkBPrirastkyButton.ButtonPushedFcn = createCallbackFcn(app, @UlozkBPrirastkyButtonPushed, true);
            app.UlozkBPrirastkyButton.Text = 'Uložiť prírastky kB';
            app.UlozPrirastkyCasuButton = uibutton(app.UIFigure, 'push');
            app.UlozPrirastkyCasuButton.ButtonPushedFcn = createCallbackFcn(app, @UlozPrirastkyCasuButtonPushed, true);
            app.UlozPrirastkyCasuButton.Text = 'Uložiť prírastky času';
            app.NatansborEditFieldLabel = uilabel(app.UIFigure);
            app.NatansborEditFieldLabel.Text = 'Načítaný súbor:';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Resetovať';
            app.UlozPacketLengthButton = uibutton(app.UIFigure, 'push');
            app.UlozPacketLengthButton.ButtonPushedFcn = createCallbackFcn(app, @UlozPacketLengthButtonPushed, true);
            app.UlozPacketLengthButton.Text = 'Uložiť veľkosti paketov';
            app.UlozTimestampButton = uibutton(app.UIFigure, 'push');
            app.UlozTimestampButton.ButtonPushedFcn = createCallbackFcn(app, @UlozTimestampButtonPushed, true);
            app.UlozTimestampButton.Text = 'Uložiť príchody časov';
            app.Panel_Hurst3D = uipanel(app.UIFigure);
            app.Panel_Hurst3D.Title = 'Hurst 3D zobrazenie';
            app.Panel_Hurst3D.Visible = 'off';
            app.UIAxesHurst3D = uiaxes(app.Panel_Hurst3D);
            title(app.UIAxesHurst3D, 'Hurst 3D zobrazenie')
            xlabel(app.UIAxesHurst3D, 'X')
            ylabel(app.UIAxesHurst3D, 'Y')
            zlabel(app.UIAxesHurst3D, 'Z')
            app.LabelHurstInfo = uilabel(app.Panel_Hurst3D);
            app.LabelHurstInfo.Text = 'Zadajte hodnoty pre výpočet Hursta (min 1 - max 5):';
            app.ButtonResetovat = uibutton(app.Panel_Hurst3D, 'push');
            app.ButtonResetovat.ButtonPushedFcn = createCallbackFcn(app, @ButtonResetovatPushed, true);
            app.ButtonResetovat.Text = 'Resetovať';
            app.ButtonVypocetHurst3D = uibutton(app.Panel_Hurst3D, 'push');
            app.ButtonVypocetHurst3D.ButtonPushedFcn = createCallbackFcn(app, @ButtonVypocetHurst3DPushed, true);
            app.ButtonVypocetHurst3D.Text = 'Vypočítať';
            app.ButtonUkonciHurst3D = uibutton(app.Panel_Hurst3D, 'push');
            app.ButtonUkonciHurst3D.ButtonPushedFcn = createCallbackFcn(app, @ButtonUkonciHurst3DPushed, true);
            app.ButtonUkonciHurst3D.Text = 'Ukončiť 3D zobrazenie';
            app.LabelHurstInfo_2 = uilabel(app.Panel_Hurst3D);
            app.LabelHurstInfo_2.Text = 'Načítaný vstupný súbor s prírastkami časov/paketov:';
            app.ButtonHurstNacitatSubor = uibutton(app.Panel_Hurst3D, 'push');
            app.ButtonHurstNacitatSubor.ButtonPushedFcn = createCallbackFcn(app, @ButtonHurstNacitatSuborPushed, true);
            app.ButtonHurstNacitatSubor.Text = 'Načítať súbor';
            app.LabelHurst3DVstup = uilabel(app.Panel_Hurst3D);
            app.LabelHurst3DVstup.Text = 'žiadny';
            app.Hodnota1Label = uilabel(app.Panel_Hurst3D);
            app.Hodnota1Label.Text = 'Hodnota 1';
            app.EditField_Hurst3D_1 = uieditfield(app.Panel_Hurst3D, 'numeric');
            app.Hodnota1Label_2 = uilabel(app.Panel_Hurst3D);
            app.Hodnota1Label_2.Text = 'Hodnota 2';
            app.EditField_Hurst3D_2 = uieditfield(app.Panel_Hurst3D, 'numeric');
            app.Hodnota1Label_3 = uilabel(app.Panel_Hurst3D);
            app.Hodnota1Label_3.Text = 'Hodnota 3';
            app.EditField_Hurst3D_3 = uieditfield(app.Panel_Hurst3D, 'numeric');
            app.Hodnota1Label_4 = uilabel(app.Panel_Hurst3D);
            app.Hodnota1Label_4.Text = 'Hodnota 4';
            app.EditField_Hurst3D_4 = uieditfield(app.Panel_Hurst3D, 'numeric');
            app.Hodnota1Label_5 = uilabel(app.Panel_Hurst3D);
            app.Hodnota1Label_5.Text = 'Hodnota 5';
            app.EditField_Hurst3D_5 = uieditfield(app.Panel_Hurst3D, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end